classdef appMLI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        SortfeaturesCheckBox        matlab.ui.control.CheckBox
        PlotButton                  matlab.ui.control.Button
        SelectsubjectDropDown       matlab.ui.control.DropDown
        SelectsubjectDropDownLabel  matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        TitleLabel                  matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        cases 
        %curCase
        MLI
        featnames
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, cases, mlires, varargin)
            app.cases = cases;
            app.SelectsubjectDropDown.Items = app.cases; 
            app.SelectsubjectDropDown.ItemsData = [app.cases];
            app.MLI = mlires;
            app.featnames = evalin('base','NM.featnames{1,1}');%varargin{1};
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            cla(app.UIAxes);
            curCaseID = app.SelectsubjectDropDown.Value;
            DDitemsdataMatChar = char(app.SelectsubjectDropDown.ItemsData);
            DDitemsdataMatNum = str2num(DDitemsdataMatChar);
            curCaseIdx = find(DDitemsdataMatNum==str2double(curCaseID));
            %app.UIAxes.Title.String = sprintf('Predictive profile of subject #%s', curCaseID);
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.TitleLabel.Text = sprintf('Predictive profile of subject #%s', curCaseID);
            barMLI(curCaseIdx, app.MLI, ...
                app.featnames, ...
                evalin('base','NM.Y{1}'), ... % change to something in handles! & add this as input argument for app
                app.SortfeaturesCheckBox.Value, ...
                app.UIAxes, ...
                app.UIAxes2);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {807, 807};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {209, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectsubjectDropDownLabel = uilabel(app.LeftPanel);
            app.SelectsubjectDropDownLabel.Text = 'Select subject';
            app.SelectsubjectDropDown = uidropdown(app.LeftPanel);
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SortfeaturesCheckBox = uicheckbox(app.LeftPanel);
            app.SortfeaturesCheckBox.Text = 'Sort features';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TitleLabel = uilabel(app.RightPanel);
            app.TitleLabel.Text = 'Title';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMLI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end