classdef appSpiderPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        ConfigPanel                matlab.ui.container.Panel
        OutputresultsPanel         matlab.ui.container.Panel
        OutputPathEditField        matlab.ui.control.EditField
        SelectdirButton            matlab.ui.control.Button
        PlotButton                 matlab.ui.control.Button
        AtlasingPanel              matlab.ui.container.Panel
        ReadtableheaderButton      matlab.ui.control.Button
        DelimiterDropDown          matlab.ui.control.DropDown
        DelimiterDropDownLabel     matlab.ui.control.Label
        ROInameDropDown            matlab.ui.control.DropDown
        ROInameDropDownLabel       matlab.ui.control.Label
        ROIIDDropDown              matlab.ui.control.DropDown
        ROIIDDropDownLabel         matlab.ui.control.Label
        ROIfileEditField           matlab.ui.control.EditField
        ROIfileEditFieldLabel      matlab.ui.control.Label
        AtlasfileEditField         matlab.ui.control.EditField
        AtlasfileEditFieldLabel    matlab.ui.control.Label
        SelectROIButton            matlab.ui.control.Button
        SelectAtlasButton          matlab.ui.control.Button
        ThresholdingPanel          matlab.ui.container.Panel
        Threshold2EditField        matlab.ui.control.NumericEditField
        Threshold2EditFieldLabel   matlab.ui.control.Label
        TwothresholdmodeCheckBox   matlab.ui.control.CheckBox
        MinimumVoxPercEditField    matlab.ui.control.NumericEditField
        MinimumLabel               matlab.ui.control.Label
        ThresholdtypeDropDown      matlab.ui.control.DropDown
        Threshold1EditField        matlab.ui.control.NumericEditField
        Threshold1EditFieldLabel   matlab.ui.control.Label
        TargetimageEditField       matlab.ui.control.EditField
        TargetimageEditFieldLabel  matlab.ui.control.Label
        AdditionalimageEditField   matlab.ui.control.EditField
        AdditionalimageLabel       matlab.ui.control.Label
        AnalyzeButton              matlab.ui.control.Button
        PlotPanel                  matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        pimg
        handles
        atlas % atlas image
        roiList % atlas regions csv
        roiT % atlas regions table
        mniIDX1
        mniIDX2
        options
        meas
        measind
        vROI
        tROI
        yROI
        yT
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, handles)

            % temp should always be the current visualisation image
            app.pimg = 'temp.nii';
            app.handles = handles; 
            % for now, this is the default atlas
            app.atlas = sprintf('%s/interpretable/anatomical/AAL3.nii', handles.NM.defs.paths);
            app.AtlasfileEditField.Value = app.atlas;
            app.roiList = sprintf('%s/interpretable/anatomical/AAL3.csv', handles.NM.defs.paths);
            app.ROIfileEditField.Value = app.roiList;
            app.roiT = readtable(app.ROIfileEditField.Value, 'Delimiter', ";");
            app.ROIIDDropDown.Items = app.roiT.Properties.VariableNames;
            app.ROIIDDropDown.ItemsData = 1:numel(app.ROIIDDropDown.Items); 
            app.ROIIDDropDown.Value=1;
            app.ROInameDropDown.Items = app.roiT.Properties.VariableNames;
            app.ROInameDropDown.ItemsData = 1:numel(app.ROIIDDropDown.Items); 
            app.ROInameDropDown.Value=2;

            % user input
            app.options = struct;
            app.options.vxcheck = 1;
            app.options.thresh = app.Threshold1EditField.Value;
            app.options.typthresh = str2double(app.ThresholdtypeDropDown.Value);
            app.options.minext = app.MinimumVoxPercEditField.Value;
            app.options.saving = 1;

            app.OutputPathEditField.Value = handles.NM.analysis{1,handles.curranal}.rootdir; % does rootdir exist?

            app.measind = handles.selVisMeas.Value;
            app.meas = handles.selVisMeas.String;
            
            switch app.meas{app.measind}
                case 'Feature weights [Overall Mean (StErr)]'
                    app.options.pattern_name = 'FeatWeights_overallM';

                case 'Feature weights [Grand Mean (StErr)]'
                    app.options.pattern_name = 'FeatWeights_grandM';

                case 'CV-ratio of feature weights [Overall Mean]'
                    app.options.pattern_name = 'CV_featWeights_overallM';

                case 'CV-ratio of feature weights [Grand Mean]'
                    app.options.pattern_name = 'CV_featWeights_grandM';

                case 'Probability of feature reliability (95%-CI) [Grand Mean]'
                    app.options.pattern_name = 'ProbFeatRel95_grandM';

                case 'Sign-based consistency'
                    app.options.pattern_name = 'SignBsdConsistency';

                case 'Sign-based consistency (Z score)'
                    app.options.pattern_name = 'SignBsdConsistency_ZScore';

                case 'Sign-based consistency -log10(P value)'
                    app.options.pattern_name = 'SignBsdConsistency_log10Pval';

                case 'Sign-based consistency -log10(P value, FDR)'
                    app.options.pattern_name = 'SignBsdConsistency_log10Pval_FDR';
                otherwise
                    app.options.pattern_name = meas{measind};
            end

            app.options.data = [];
            app.options.compute_voxelstats = 0;
            app.TwothresholdmodeCheckBox.Value = 0;
%             [vROI, tROI, yROI, yT] = nk_aal_volume_roi(app.pimg, ...
%                 [], ...
%                 app.atlas, ...
%                 app.roiList, ...
%                 app.mniIDX1, ...
%                 app.mniIDX2, ...
%                 app.options);
% 
%             rois = readtable(app.roiList); % should be in this folder in NM (later: user can define atlas) 
%             plot_spider_plot(tROI,rois, app.UIAxes);

%             rois = readtable('visual/AAL2.csv'); % should be in this folder in NM (later: user can define atlas) 
%             tROI.Properties.RowNames = tROI.AnatomicalRegion;
% 
%             
%             for i=1:height(rois)
%                 lbl = rois.Var2{i};
%                 try rois.tROI(i) = table2array(tROI(lbl, 6)); catch; end
%             end
%             rois(rois.tROI==0,:) = [];
%             P = rois.tROI';
% %             y=120;
% %             
%             spider_plot(P, ...
%                 'axesLabels', rois.Var2,...
%                 'axesLimits', [zeros(1,y); 100*ones(1,y)], ...
%                 'axesInterval', 1, ...
%                 'axesLabelsEdge','none', ...
%                 'axesDisplay', 'one', ...
%                 'axesLabelsOffset', 0.05, ...
%                 'axesFontSize', 12,...
%                 'labelFontSize', 9, ...
%                 'lineWidth',2,...
%                 'fillOption', 'on',...
%                 'markerSize', 18,...
%                 'axesHorzAlign', 'quadrant', ...
%                 'axesVertAlign', 'quadrant', ...
%                 'axesLabelsRotate', 'on', ...
%                 'axesDirection', 'normal', ...
%                 'axesPrecision',0,...
%                 'axesZero','on',...
%                 'axesLabelsRotate','on',...
%                 'axesOffset',1, ...
%                 'axesobject', app.UIAxes);

        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)

            app.options = struct;
            app.options.vxcheck = 1;
            if app.TwothresholdmodeCheckBox.Value
                app.options.thresh = [app.Threshold1EditField.Value app.Threshold2EditField.Value];
            else
                app.options.thresh = app.Threshold1EditField.Value;
            end
            app.options.typthresh = app.ThresholdtypeDropDown.Value;
            app.options.minext = app.MinimumVoxPercEditField.Value;
            app.options.saving = 1;
            app.options.name = app.OutputPathEditField.Value; % does rootdir exist?

            switch app.meas{app.measind}
                case 'Feature weights [Overall Mean (StErr)]'
                    app.options.pattern_name = 'FeatWeights_overallM';

                case 'Feature weights [Grand Mean (StErr)]'
                    app.options.pattern_name = 'FeatWeights_grandM';

                case 'CV-ratio of feature weights [Overall Mean]'
                    app.options.pattern_name = 'CV_featWeights_overallM';

                case 'CV-ratio of feature weights [Grand Mean]'
                    app.options.pattern_name = 'CV_featWeights_grandM';

                case 'Probability of feature reliability (95%-CI) [Grand Mean]'
                    app.options.pattern_name = 'ProbFeatRel95_grandM';

                case 'Sign-based consistency'
                    app.options.pattern_name = 'SignBsdConsistency';

                case 'Sign-based consistency (Z score)'
                    app.options.pattern_name = 'SignBsdConsistency_ZScore';

                case 'Sign-based consistency -log10(P value)'
                    app.options.pattern_name = 'SignBsdConsistency_log10Pval';

                case 'Sign-based consistency -log10(P value, FDR)'
                    app.options.pattern_name = 'SignBsdConsistency_log10Pval_FDR';

                otherwise
                    app.options.pattern_name = meas{measind};
            end

            app.options.data = [];
            app.options.compute_voxelstats = 0;
            [app.vROI, app.tROI, app.yROI, app.yT] = nk_ROImapper(app.pimg, ...
                [], ...
                app.atlas, ...
                app.roiList, ...
                app.ROIIDDropDown.Value, ...
                app.ROInameDropDown.Value, ...
                app.options);

            app.PlotButton.Enable="on";
            drawnow
        end

        % Value changed function: Threshold1EditField
        function Threshold1EditFieldValueChanged(app, event)
            value = app.Threshold1EditField.Value;
        end

        % Value changed function: TwothresholdmodeCheckBox
        function TwothresholdmodeCheckBoxValueChanged(app, event)
            value = app.TwothresholdmodeCheckBox.Value;
            if value
                 app.ThresholdtypeDropDown.Items = {'<|>','<=|>=','>|<','>=|<='};
                 app.ThresholdtypeDropDown.ItemsData = 1:4;
                 app.Threshold2EditField.Enable = "on";
                 app.Threshold2EditFieldLabel.Enable = "on";
            else
                 app.ThresholdtypeDropDown.Items = {'<','<=','>','>=','=='};
                 app.ThresholdtypeDropDown.ItemsData = 1:5;
                 app.Threshold2EditField.Enable = "off";
                 app.Threshold2EditFieldLabel.Enable = "off";
            end
            drawnow
        end

        % Button pushed function: SelectAtlasButton
        function SelectAtlasButtonPushed(app, event)
            if isempty(app.AtlasfileEditField.Value)
                defatlas = fullfile(app.handles.NM.defs.paths,'interpretable','anatomical','aal3.nii');
            else
                defatlas = app.AtlasfileEditField.Value;
            end
            [atlas_file,atlas_path] = uigetfile({'*.nii','NIfTI-files (*.nii)'; ...
                '*.img','NIftI/ANALYZE images (*.img)'},'Select atlas image file', defatlas);
            app.atlas = fullfile(atlas_path,atlas_file);
            app.AtlasfileEditField.Value = app.atlas;
            app.AtlasfileEditField.Enable = "on";
            drawnow
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)
            if isempty(app.ROIfileEditField.Value)
                defroi = fullfile(app.handles.NM.defs.paths,'interpretable','anatomical','aal3.csv');
            else
                defroi = app.ROIfileEditField.Value;
            end
            [roi_file, roi_path] = uigetfile({'*.csv','CSV-files (*.csv)'; ...
                '*.txt','Text files (*.txt)'},'Select atlas ROI descriptor file', defroi);
            app.roiList = fullfile(roi_path,roi_file);
            app.ROIfileEditField.Value = app.roiList;
            app.ROIfileEditField.Enable = "on";
            app.DelimiterDropDown.Enable = "on";
            app.ReadtableheaderButton.Enable = "on";
            drawnow
        end

        % Value changed function: DelimiterDropDown
        function DelimiterDropDownValueChanged(app, event)
            
        end

        % Button pushed function: ReadtableheaderButton
        function ReadtableheaderButtonPushed(app, event)
            delimiters = {';',',','space','tab'};
            value = app.DelimiterDropDown.Value;
            try
                 app.roiT = readtable(app.ROIfileEditField.Value, 'Delimiter', delimiters{value});
            catch ERR
                errordlg(sprintf('The atlas descriptor file %s could not be read!\nError: %s\nCheck your settings!', ...
                    app.ROIfileEditField.Value, ERR.message), 'Spider plot');
            end
            if ~isempty(app.roiT)
                if numel(app.roiT.Properties.VariableNames)>1
                        app.ROIIDDropDown.Enable="on";
                        app.ROInameDropDown.Enable="on";
                        app.ROIIDDropDown.Items = app.roiT.Properties.VariableNames;
                        app.ROIIDDropDown.ItemsData = 1:numel(app.ROIIDDropDown.Items); 
                        app.ROInameDropDown.Items = app.roiT.Properties.VariableNames;
                        app.ROInameDropDown.ItemsData = 1:numel(app.ROIIDDropDown.Items); 
                else
                    app.ROIIDDropDown.Enable="off";
                    app.ROInameDropDown.Enable="off";
                end
            end
            drawnow
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plot_spider_plot(app.tROI, app.roiT, app.UIAxes);
            drawnow
        end

        % Button pushed function: SelectdirButton
        function SelectdirButtonPushed(app, event)
            app.OutputPathEditField.Value = uigetdir(app.handles.NM.analysis{app.handles.curranal}.rootdir);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {704, 704};
                app.GridLayout.ColumnWidth = {'1x'};
                app.PlotPanel.Layout.Row = 2;
                app.PlotPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {207, '1x'};
                app.PlotPanel.Layout.Row = 1;
                app.PlotPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ConfigPanel = uipanel(app.GridLayout);
            app.ConfigPanel.Tooltip = {''};
            app.ConfigPanel.Title = 'Configuration';
            app.AnalyzeButton = uibutton(app.ConfigPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.AdditionalimageLabel = uilabel(app.ConfigPanel);
            app.AdditionalimageLabel.Enable = 'off';
            app.AdditionalimageLabel.Text = 'Additional image';
            app.AdditionalimageEditField = uieditfield(app.ConfigPanel, 'text');
            app.AdditionalimageEditField.Editable = 'off';
            app.AdditionalimageEditField.Enable = 'off';
            app.AdditionalimageEditField.Tooltip = {'(optional) paths to additional statistical images to be co-analyzed with Pimg <char array>'};
            app.AdditionalimageEditField.Value = 'optional';
            app.TargetimageEditFieldLabel = uilabel(app.ConfigPanel);
            app.TargetimageEditFieldLabel.Enable = 'off';
            app.TargetimageEditFieldLabel.Text = 'Target image';
            app.TargetimageEditField = uieditfield(app.ConfigPanel, 'text');
            app.TargetimageEditField.Editable = 'off';
            app.TargetimageEditField.Enable = 'off';
            app.TargetimageEditField.Tooltip = {'paths to statistical images <char array>'};
            app.ThresholdingPanel = uipanel(app.ConfigPanel);
            app.ThresholdingPanel.Title = 'Thresholding';
            app.Threshold1EditFieldLabel = uilabel(app.ThresholdingPanel);
            app.Threshold1EditFieldLabel.Text = 'Threshold 1';
            app.Threshold1EditField = uieditfield(app.ThresholdingPanel, 'numeric');
            app.Threshold1EditField.ValueChangedFcn = createCallbackFcn(app, @Threshold1EditFieldValueChanged, true);
            app.ThresholdtypeDropDown = uidropdown(app.ThresholdingPanel);
            app.ThresholdtypeDropDown.Items = {'<', '<=', '>', '>=', '=='};
            app.ThresholdtypeDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.ThresholdtypeDropDown.Value = '1';
            app.MinimumLabel = uilabel(app.ThresholdingPanel);
            app.MinimumLabel.Tooltip = {''};
            app.MinimumLabel.Text = 'Minimum % of voxels in ROI  ';
            app.MinimumVoxPercEditField = uieditfield(app.ThresholdingPanel, 'numeric');
            app.MinimumVoxPercEditField.Value = 1;
            app.TwothresholdmodeCheckBox = uicheckbox(app.ThresholdingPanel);
            app.TwothresholdmodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @TwothresholdmodeCheckBoxValueChanged, true);
            app.TwothresholdmodeCheckBox.Text = 'Two-threshold mode';
            app.Threshold2EditFieldLabel = uilabel(app.ThresholdingPanel);
            app.Threshold2EditFieldLabel.Text = 'Threshold 2';
            app.Threshold2EditField = uieditfield(app.ThresholdingPanel, 'numeric');
            app.AtlasingPanel = uipanel(app.ConfigPanel);
            app.AtlasingPanel.Title = 'Atlasing';
            app.SelectAtlasButton = uibutton(app.AtlasingPanel, 'push');
            app.SelectAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAtlasButtonPushed, true);
            app.SelectAtlasButton.Text = 'Select Atlas';
            app.SelectROIButton = uibutton(app.AtlasingPanel, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Text = 'Select ROI';
            app.AtlasfileEditFieldLabel = uilabel(app.AtlasingPanel);
            app.AtlasfileEditFieldLabel.Enable = 'off';
            app.AtlasfileEditFieldLabel.Text = 'Atlas file';
            app.AtlasfileEditField = uieditfield(app.AtlasingPanel, 'text');
            app.AtlasfileEditField.Enable = 'off';
            app.AtlasfileEditField.Tooltip = {'path to parcellation image <char>'};
            app.ROIfileEditFieldLabel = uilabel(app.AtlasingPanel);
            app.ROIfileEditFieldLabel.Enable = 'off';
            app.ROIfileEditFieldLabel.Text = 'ROI file';
            app.ROIfileEditField = uieditfield(app.AtlasingPanel, 'text');
            app.ROIfileEditField.Enable = 'off';
            app.ROIfileEditField.Tooltip = {'path to csv or excel file with parcellation info, which should contain the the id column indexing parcellations '; 'in the parcellation image <char>'};
            app.ROIIDDropDownLabel = uilabel(app.AtlasingPanel);
            app.ROIIDDropDownLabel.Enable = 'off';
            app.ROIIDDropDownLabel.Text = 'ROI ID';
            app.ROIIDDropDown = uidropdown(app.AtlasingPanel);
            app.ROIIDDropDown.Enable = 'off';
            app.ROInameDropDownLabel = uilabel(app.AtlasingPanel);
            app.ROInameDropDownLabel.Enable = 'off';
            app.ROInameDropDownLabel.Text = 'ROI name';
            app.ROInameDropDown = uidropdown(app.AtlasingPanel);
            app.ROInameDropDown.Enable = 'off';
            app.DelimiterDropDownLabel = uilabel(app.AtlasingPanel);
            app.DelimiterDropDownLabel.Enable = 'off';
            app.DelimiterDropDownLabel.Text = 'Delimiter';
            app.DelimiterDropDown = uidropdown(app.AtlasingPanel);
            app.DelimiterDropDown.Items = {'semicolon', 'comma', 'space', 'tabulator'};
            app.DelimiterDropDown.ItemsData = [1 2 3 4];
            app.DelimiterDropDown.Editable = 'on';
            app.DelimiterDropDown.ValueChangedFcn = createCallbackFcn(app, @DelimiterDropDownValueChanged, true);
            app.DelimiterDropDown.Enable = 'off';
            app.DelimiterDropDown.Value = 1;
            app.ReadtableheaderButton = uibutton(app.AtlasingPanel, 'push');
            app.ReadtableheaderButton.ButtonPushedFcn = createCallbackFcn(app, @ReadtableheaderButtonPushed, true);
            app.ReadtableheaderButton.Enable = 'off';
            app.ReadtableheaderButton.Text = 'Read table header';
            app.PlotButton = uibutton(app.ConfigPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.OutputresultsPanel = uipanel(app.ConfigPanel);
            app.OutputresultsPanel.Title = 'Output results';
            app.SelectdirButton = uibutton(app.OutputresultsPanel, 'push');
            app.SelectdirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdirButtonPushed, true);
            app.SelectdirButton.Text = 'Select dir';
            app.OutputPathEditField = uieditfield(app.OutputresultsPanel, 'text');
            app.PlotPanel = uipanel(app.GridLayout);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Spider Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'parula')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appSpiderPlot(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end